classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WybranascenaLabel              matlab.ui.control.Label
        WybierzinnasceneButton         matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        MapacieplnaButton              matlab.ui.control.Button
        MapafiksacjiButton             matlab.ui.control.Button
        WybierzAOIPanel                matlab.ui.container.Panel
        ZegarCheckBox                  matlab.ui.control.CheckBox
        ReklamaCheckBox                matlab.ui.control.CheckBox
        ZaladujAOIButton               matlab.ui.control.Button
        FilmCheckBox_2                 matlab.ui.control.CheckBox
        TestwybranychAOITextAreaLabel  matlab.ui.control.Label
        TestwybranychAOITextArea       matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WybierzinnasceneButton
        function WybierzinnasceneButtonPushed(app, event)
            delete(app)
            app1
            
        end

        % Button pushed function: ZaladujAOIButton
        function ZaladujAOIButtonPushed(app, event)

            value_film = get(app.FilmCheckBox_2, 'Value');
            value_reklama = get(app.ReklamaCheckBox, 'Value');
            value_zegar = get(app.ZegarCheckBox, 'Value');
            
            answer = num2str(value_reklama);
            
            if (value_reklama == 1 && value_zegar == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Zegar';
            elseif (value_reklama == 1 && value_zegar == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Film';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama, Film i Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar i Film';
            elseif (value_film == 1 && value_reklama == 0 && value_zegar == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Film';
        
            end
        end

        % Button pushed function: MapacieplnaButton
        function MapacieplnaButtonPushed(app, event)
            %Skrypt do mapy cieplnej
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WybranascenaLabel = uilabel(app.UIFigure);
            app.WybranascenaLabel.Text = 'Wybrana scena';
            app.WybierzinnasceneButton = uibutton(app.UIFigure, 'push');
            app.WybierzinnasceneButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzinnasceneButtonPushed, true);
            app.WybierzinnasceneButton.Text = 'Wybierz inna scene';
            app.Image = uiimage(app.UIFigure);
            app.MapacieplnaButton = uibutton(app.UIFigure, 'push');
            app.MapacieplnaButton.ButtonPushedFcn = createCallbackFcn(app, @MapacieplnaButtonPushed, true);
            app.MapacieplnaButton.Text = 'Mapa cieplna';
            app.MapafiksacjiButton = uibutton(app.UIFigure, 'push');
            app.MapafiksacjiButton.Text = 'Mapa fiksacji';
            app.WybierzAOIPanel = uipanel(app.UIFigure);
            app.WybierzAOIPanel.Title = 'Wybierz AOI';
            app.ZegarCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ZegarCheckBox.Text = 'Zegar';
            app.FilmCheckBox_2 = uicheckbox(app.WybierzAOIPanel);
            app.FilmCheckBox_2.Text = 'Film';
            app.ReklamaCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ReklamaCheckBox.Text = 'Reklama';
            app.ZaladujAOIButton = uibutton(app.UIFigure, 'push');
            app.ZaladujAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ZaladujAOIButtonPushed, true);
            app.ZaladujAOIButton.Text = 'Zaladuj AOI';
            app.TestwybranychAOITextAreaLabel = uilabel(app.UIFigure);
            app.TestwybranychAOITextAreaLabel.Text = 'Test wybranych AOI';
            app.TestwybranychAOITextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end