classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjektZespolowyLabel          matlab.ui.control.Label
        WyborscenyPanel                matlab.ui.container.Panel
        WybierzfilmDropDownLabel       matlab.ui.control.Label
        WybierzfilmDropDown            matlab.ui.control.DropDown
        WybierzsceneDropDownLabel      matlab.ui.control.Label
        WybierzsceneDropDown           matlab.ui.control.DropDown
        WybierzreklameDropDownLabel    matlab.ui.control.Label
        WybierzreklameDropDown         matlab.ui.control.DropDown
        ZaadujButton                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        WybierzAOIPanel                matlab.ui.container.Panel
        ZegarCheckBox                  matlab.ui.control.CheckBox
        FilmCheckBox                   matlab.ui.control.CheckBox
        ReklamaCheckBox                matlab.ui.control.CheckBox
        MapacieplnaButton              matlab.ui.control.Button
        MapafiksacjiButton             matlab.ui.control.Button
        ZaladujAOIButton               matlab.ui.control.Button
        TestwybranychAOITextAreaLabel  matlab.ui.control.Label
        TestwybranychAOITextArea       matlab.ui.control.TextArea
        WczytajplikwynikowyeksperymentuPanel  matlab.ui.container.Panel
        WybierzplikButton              matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZaadujButton
        function ZaadujButtonPushed(app, event)
            %zmienne z dropdown
            value_movie = get(app.WybierzfilmDropDown, 'Value');
            value_reklama = get(app.WybierzreklameDropDown, 'Value');
            value_scena = get(app.WybierzsceneDropDown, 'Value');
            %ustawienia dla wykresow
            title(app.UIAxes, 'Scena');
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            %nazwy grafik do scen
            fname111 = 'Screeny_poprawione/f1r1s1.png';
            fname112 = 'Screeny_poprawione/f1r1s2.png';
            fname113 = 'Screeny_poprawione/f1r1s3.png';
            fname121 = 'Screeny_poprawione/f1r2s1.png';
            fname122 = 'Screeny_poprawione/f1r2s2.png';
            fname123 = 'Screeny_poprawione/f1r2s3.png';
            fname131 = 'Screeny_poprawione/f1r3s1.png';
            fname132 = 'Screeny_poprawione/f1r3s2.png';
            fname133 = 'Screeny_poprawione/f1r3s3.png';
            
            fname211 = 'Screeny_poprawione/f2r1s1.png';
            fname212 = 'Screeny_poprawione/f2r1s2.png';
            fname213 = 'Screeny_poprawione/f2r1s3.png';
            fname214 = 'Screeny_poprawione/f2r1s4.png';
            fname215 = 'Screeny_poprawione/f2r1s5.png';
            fname216 = 'Screeny_poprawione/f2r1s6.png';
            fname221 = 'Screeny_poprawione/f2r2s1.png';
            fname222 = 'Screeny_poprawione/f2r2s2.png';
            fname223 = 'Screeny_poprawione/f2r2s3.png';
            fname224 = 'Screeny_poprawione/f2r2s4.png';
            fname225 = 'Screeny_poprawione/f2r2s5.png';
            fname226 = 'Screeny_poprawione/f2r2s6.png';
            fname231 = 'Screeny_poprawione/f2r3s1.png';
            fname232 = 'Screeny_poprawione/f2r3s2.png';
            fname233 = 'Screeny_poprawione/f2r3s3.png';
            fname234 = 'Screeny_poprawione/f2r3s4.png';
            fname235 = 'Screeny_poprawione/f2r3s5.png';
            fname236 = 'Screeny_poprawione/f2r3s6.png';
            
            if(value_movie == 'Film 1')
                if(value_reklama == 'Reklama 1')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 1, Reklame 1, Scene 1';
                        imshow(fname111,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 1, Reklame 1, Scene 2';
                        imshow(fname112,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 1, Reklame 1, Scene 3';
                        imshow(fname113,'Parent', app.UIAxes);
                    elseif(value_scena == 'Scena 4')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    end
                elseif (value_reklama == 'Reklama 2')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 1, Reklame 2, Scene 1';
                        imshow(fname121,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 1, Reklame 2, Scene 2';
                        imshow(fname122,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 1, Reklame 2, Scene 3';
                        imshow(fname123,'Parent', app.UIAxes);
                    elseif(value_scena == 'Scena 4')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    end
                elseif (value_reklama == 'Reklama 3')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 1, Reklame 3, Scene 1';
                        imshow(fname131,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 1, Reklame 3, Scene 2';
                        imshow(fname132,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 1, Reklame 3, Scene 3';
                        imshow(fname133,'Parent', app.UIAxes);
                    elseif(value_scena == 'Scena 4')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Brak sceny dla tego filmu i reklamy';
                    end
                end
                
            elseif (value_movie == 'Film 2')
                if(value_reklama == 'Reklama 1')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 1';
                        imshow(fname211,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 2';
                        imshow(fname212,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 3';
                        imshow(fname213,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 4')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 4';
                        imshow(fname214,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 5';
                        imshow(fname215,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Wybrano Film 2, Reklame 1, Scene 6';
                        imshow(fname216,'Parent', app.UIAxes);
                    end
                elseif (value_reklama == 'Reklama 2')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 1';
                        imshow(fname221,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 2';
                        imshow(fname222,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 3';
                        imshow(fname223,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 4')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 4';
                        imshow(fname224,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 5';
                        imshow(fname225,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Wybrano Film 2, Reklame 2, Scene 6';
                        imshow(fname226,'Parent', app.UIAxes);
                    end
                elseif (value_reklama == 'Reklama 3')
                    if(value_scena == 'Scena 1')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 1';
                        imshow(fname231,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 2')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 2';
                        imshow(fname232,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 3')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 3';
                        imshow(fname233,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 4')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 4';
                        imshow(fname234,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 5')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 5';
                        imshow(fname235,'Parent', app.UIAxes);
                    elseif (value_scena == 'Scena 6')
                        app.Label.Text = 'Wybrano Film 2, Reklame 3, Scene 6';
                        imshow(fname236,'Parent', app.UIAxes);
                    end
                end
            end
        end

        % Button pushed function: ZaladujAOIButton
        function ZaladujAOIButtonPushed(app, event)
            value_film = get(app.FilmCheckBox, 'Value');
            value_reklama = get(app.ReklamaCheckBox, 'Value');
            value_zegar = get(app.ZegarCheckBox, 'Value');
            
            answer = num2str(value_reklama);
            
            if (value_reklama == 1 && value_zegar == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Zegar';
            elseif (value_reklama == 1 && value_zegar == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama i Film';
            elseif (value_reklama == 1 && value_zegar == 1 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Reklama, Film i Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar';
            elseif (value_zegar == 1 && value_reklama == 0 && value_film == 1)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Zegar i Film';
            elseif (value_film == 1 && value_reklama == 0 && value_zegar == 0)
                app.TestwybranychAOITextArea.Value = 'Wybrano obszar zainteresowań Film';
        
            end
        end

        % Button pushed function: MapacieplnaButton
        function MapacieplnaButtonPushed(app, event)
            %Skrypt do mapy cieplnej
            %run(mapacieplna)
            
            %----------------------Script do mapy cieplnej-----------------
            obrazek_kobra = imread('Kobra-krolewska.jpg');
            %obrazek_rodzina = imread('rodzina.jpg');
            obrazek = obrazek_kobra;
            %obrazek = obrazek_rodzina
            %fixations = readtable('User0_fixations.csv');
            
            fixations = readtable(app.file);
            img_height = size(obrazek,1);
            img_width = size(obrazek,2);
            %fix=fixations(1:15,[6,7,9]); %dla rodziny
            fix=fixations(16:end,[6,7,9]); %dla kobry
            xd = table2array(fix);

            xd(:,1) = xd(:,1)*img_height;
            xd(:,2) = xd(:,2)*img_width;
            longest_fix = max(xd(:,3)); 
            % fixations(:,5) -czas fiksacji
            HM = uint8(zeros(img_height,img_width,1));
            temp = uint8(zeros(img_height,img_width,1));
            for j = 1:size(xd,1)
                a = floor((xd(j,3)*255)/longest_fix);
                color_new = [a,a,a];
                HM  = HM + imgaussfilt(insertShape(temp,'FilledCircle',[xd(j,2) xd(j,1) 40],'Color', color_new,'Opacity',1.0),10);
            end
            HM = double(HM(:,:,1));
            HM = HM/max(max(HM));

            alpha = HM;
            alpha2 = HM;
            alpha2(HM>0)=1;

            HM2 = HM.*255;
            HM2 = floor(HM2);
            HM2 = uint8(HM2);
            rgbImage = ind2rgb(HM2, jet(256));

            beta = ones(size(alpha2));
            beta = beta-alpha2;
            BG2 = (double(obrazek)./255);
            f = rgbImage .* alpha2;
            b = BG2 .* beta;
            rgbImage2 = f + b;
            
            %app.UIAxes = uiaxes(app.UIFigure);
            % Fill figure with axes and remove tick labels-na caly ekran
            %app.UIAxes.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, 'Mapa cieplna');
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            imshow(rgbImage2,'Parent', app.UIAxes);
            %------------------Koniec scriptu do mapy cieplnej---------
        end

        % Button pushed function: WybierzplikButton
        function WybierzplikButtonPushed(app, event)
            global archivo
             [ filename, pathname ] = uigetfile({'*.csv'},'File Selector');
             fullpathname = strcat(pathname,filename);
             archivo = xlsread(fullpathname);
             %Pobrano nazwe pliku
             app.Label_2.Text = filename;
             app.file = filename;
             %app.UITable.Data = array2table(archivo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjektZespolowyLabel = uilabel(app.UIFigure);
            app.ProjektZespolowyLabel.Text = 'Projekt Zespolowy';
            app.WyborscenyPanel = uipanel(app.UIFigure);
            app.WyborscenyPanel.Title = 'Wybor sceny';
            app.WybierzfilmDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzfilmDropDownLabel.Text = 'Wybierz film';
            app.WybierzfilmDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzfilmDropDown.Items = {'Wybierz', 'Film 1', 'Film 2'};
            app.WybierzfilmDropDown.Value = 'Wybierz';
            app.WybierzsceneDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzsceneDropDownLabel.Text = 'Wybierz scene';
            app.WybierzsceneDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzsceneDropDown.Items = {'Scena 1', 'Scena 2', 'Scena 3', 'Scena 4', 'Scena 5', 'Scena 6'};
            app.WybierzsceneDropDown.Value = 'Scena 1';
            app.WybierzreklameDropDownLabel = uilabel(app.WyborscenyPanel);
            app.WybierzreklameDropDownLabel.Text = 'Wybierz  reklame';
            app.WybierzreklameDropDown = uidropdown(app.WyborscenyPanel);
            app.WybierzreklameDropDown.Items = {'Reklama 1', 'Reklama 2', 'Reklama 3'};
            app.WybierzreklameDropDown.Value = 'Reklama 1';
            app.ZaadujButton = uibutton(app.UIFigure, 'push');
            app.ZaadujButton.ButtonPushedFcn = createCallbackFcn(app, @ZaadujButtonPushed, true);
            app.ZaadujButton.Text = 'Załaduj';
            app.Label = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.WybierzAOIPanel = uipanel(app.UIFigure);
            app.WybierzAOIPanel.Title = 'Wybierz AOI';
            app.ZegarCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ZegarCheckBox.Text = 'Zegar';
            app.FilmCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.FilmCheckBox.Text = 'Film';
            app.ReklamaCheckBox = uicheckbox(app.WybierzAOIPanel);
            app.ReklamaCheckBox.Text = 'Reklama';
            app.MapacieplnaButton = uibutton(app.UIFigure, 'push');
            app.MapacieplnaButton.ButtonPushedFcn = createCallbackFcn(app, @MapacieplnaButtonPushed, true);
            app.MapacieplnaButton.Text = 'Mapa cieplna';
            app.MapafiksacjiButton = uibutton(app.UIFigure, 'push');
            app.MapafiksacjiButton.Text = 'Mapa fiksacji';
            app.ZaladujAOIButton = uibutton(app.UIFigure, 'push');
            app.ZaladujAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ZaladujAOIButtonPushed, true);
            app.ZaladujAOIButton.Text = 'Zaladuj AOI';
            app.TestwybranychAOITextAreaLabel = uilabel(app.UIFigure);
            app.TestwybranychAOITextAreaLabel.Text = 'Test wybranych AOI';
            app.TestwybranychAOITextArea = uitextarea(app.UIFigure);
            app.WczytajplikwynikowyeksperymentuPanel = uipanel(app.UIFigure);
            app.WczytajplikwynikowyeksperymentuPanel.Title = 'Wczytaj plik wynikowy eksperymentu';
            app.WybierzplikButton = uibutton(app.WczytajplikwynikowyeksperymentuPanel, 'push');
            app.WybierzplikButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzplikButtonPushed, true);
            app.WybierzplikButton.Text = 'Wybierz plik';
            app.Label_2 = uilabel(app.WczytajplikwynikowyeksperymentuPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end